classdef PIETOOLS_2021a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PIETOOLS2021aUIFigure       matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        AddnewBCButton              matlab.ui.control.Button
        AddtoPanel                  matlab.ui.container.Panel
        DynamicsofButton            matlab.ui.control.Button
        LHSDropDown                 matlab.ui.control.DropDown
        BCNumberButton              matlab.ui.control.Button
        BCNumber                    matlab.ui.control.DropDown
        ORLabel                     matlab.ui.control.Label
        AddTermPanel                matlab.ui.container.Panel
        Coefficient                 matlab.ui.control.EditField
        ProductList                 matlab.ui.control.DropDown
        TermType                    matlab.ui.control.DropDown
        IntVariable                 matlab.ui.control.DropDown
        aEditFieldLabel             matlab.ui.control.Label
        lowerLimit                  matlab.ui.control.EditField
        bEditFieldLabel             matlab.ui.control.Label
        UpperLimit                  matlab.ui.control.EditField
        CenterPanel                 matlab.ui.container.Panel
        TextArea                    matlab.ui.control.TextArea
        TextArea_2                  matlab.ui.control.TextArea
        DynamicsEqns                matlab.ui.control.UIAxes
        BCEqns                      matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        ODElist                     matlab.ui.control.Table
        VariableNameEditFieldLabel  matlab.ui.control.Label
        VariableNameEditField       matlab.ui.control.EditField
        AddPDEstateButton           matlab.ui.control.Button
        AddODEstateButton           matlab.ui.control.Button
        PDElist                     matlab.ui.control.Table
        Inputlist                   matlab.ui.control.Table
        AddInputButton              matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitializeGUI(app)
            set(app.LHSDropDown,'Items',{});
            set(app.ProductList,'Items',{});
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PIETOOLS2021aUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {249, '1x', 218};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: AddPDEstateButton
        function addstatePDE(app, event)
            % need to check if the variable already exists and then add
            
            state_name = convertCharsToStrings(get(app.VariableNameEditField, 'Value'));

            
            ODEvarlist = get(app.ODElist,'Data');
            PDEvarlist = get(app.PDElist,'Data');
            Inputvarlist = get(app.Inputlist,'Data');
            
            if isempty(PDEvarlist)
                PDEvarlist = zeros([0,2]);
            end
            
            if ~any(strcmp(ODEvarlist,state_name)) && ...
                    ~any(strcmp(PDEvarlist(:,1),state_name)) && ...
                    ~any(strcmp(Inputvarlist,state_name))
                tabledata = get(app.PDElist, 'Data');
                tabledata = [tabledata; [state_name 0]];
                set(app.PDElist, 'Data', tabledata);
            
            % add state to dynamics
            state_name_t = convertStringsToChars(strcat(state_name,"_t"));
            dynamics_list = get(app.LHSDropDown,'Items');
            dynamics_list{end+1} = state_name_t;
            set(app.LHSDropDown,'Items', dynamics_list);
            
            textDyn = get(app.TextArea,'Value');
            textDyn{end+1} = ['$', state_name_t,' = 0$'];
            set(app.TextArea,'Value',textDyn);
            
            textDyn=erase(textDyn,'*');
            cla(app.DynamicsEqns);
            text(app.DynamicsEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            
            RHS_terms_list = get(app.ProductList, 'Items');
            RHS_terms_list{end+1} = [get(app.VariableNameEditField, 'Value'),'(s)'];
            RHS_terms_list{end+1} = [get(app.VariableNameEditField, 'Value'),'(\theta)'];
            set(app.ProductList,'Items', RHS_terms_list);
            end
        end

        % Button pushed function: AddODEstateButton
        function addstateODE(app, event)
            % need to check if the variable already exists and then add
            state_name = convertCharsToStrings(get(app.VariableNameEditField, 'Value'));
            ODEvarlist = get(app.ODElist,'Data');
            PDEvarlist = get(app.PDElist,'Data');
            Inputvarlist = get(app.Inputlist,'Data');
            
            if isempty(PDEvarlist)
                PDEvarlist = zeros([0,2]);
            end
            
            if ~any(strcmp(ODEvarlist,state_name)) && ...
                    ~any(strcmp(PDEvarlist(:,1),state_name)) && ...
                    ~any(strcmp(Inputvarlist,state_name))
                tabledata = get(app.ODElist, 'Data');
                tabledata = [tabledata; state_name];
                set(app.ODElist, 'Data', tabledata);
            
            % add state to dynamics
            state_name_t = convertStringsToChars(strcat(state_name,"_t"));
            dynamics_list = get(app.LHSDropDown,'Items');
            
            dynamics_list{end+1} = state_name_t;
            set(app.LHSDropDown,'Items', dynamics_list);
            
            textDyn = get(app.TextArea,'Value');
            textDyn{end+1} = ['$',state_name_t,' = 0$'];
            set(app.TextArea,'Value',textDyn);
            
            textDyn=erase(textDyn,'*');
            cla(app.DynamicsEqns);
            text(app.DynamicsEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            
            RHS_terms_list = get(app.ProductList, 'Items');
            RHS_terms_list{end+1} = get(app.VariableNameEditField, 'Value');
            set(app.ProductList,'Items', RHS_terms_list);
            end
            
        end

        % Button pushed function: AddInputButton
        function addInput(app, event)
            % need to check if the variable already exists and then add
            input_name = convertCharsToStrings(get(app.VariableNameEditField, 'Value'));
            ODEvarlist = get(app.ODElist,'Data');
            PDEvarlist = get(app.PDElist,'Data');
            Inputvarlist = get(app.Inputlist,'Data');
            
            if isempty(PDEvarlist)
                PDEvarlist = zeros([0,2]);
            end
            
            if ~any(strcmp(ODEvarlist,input_name)) && ...
                    ~any(strcmp(PDEvarlist(:,1),input_name)) && ...
                    ~any(strcmp(Inputvarlist,input_name))
            tabledata = get(app.Inputlist, 'Data');
            tabledata = [tabledata; input_name];
            set(app.Inputlist, 'Data', tabledata);
            
            RHS_terms_list = get(app.ProductList, 'Items');
            RHS_terms_list{end+1} = get(app.VariableNameEditField, 'Value');
            set(app.ProductList,'Items', RHS_terms_list);
            end
        end

        % Button pushed function: DynamicsofButton
        function addTerms2Dynamics(app, event)
            a = get(app.lowerLimit, 'Value');
            b = get(app.UpperLimit, 'Value');
            termType = get(app.TermType,'Value');
            coeff = get(app.Coefficient,'Value');
            
            stateterm = get(app.ProductList,'Value');
            LHS = get(app.LHSDropDown,'Value');
            dynamicstype = LHS(1:end-2);
            
            ODEvarlist = get(app.ODElist,'Data');
            PDEvarlist = get(app.PDElist,'Data');
            Inputvarlist = get(app.Inputlist,'Data');
            if any(strcmp(ODEvarlist,dynamicstype)) 
                dynamicstype='ODE';
            elseif any(strcmp(PDEvarlist(:,1),dynamicstype))
                dynamicstype='PDE';
            end
            intvar = get(app.IntVariable,'Value');
            
            logicalval = isvalidPolynomial(coeff);
            
            coeff = replace(coeff,'theta','\theta');
            validTerm = isvalidTerm(coeff,termType,dynamicstype,stateterm,intvar);
            if logicalval==0 && validTerm==0
                textDyn = get(app.TextArea,'Value');
                idx = find(contains(textDyn,LHS));
                existingTxt = textDyn{idx};
                
                if strcmp(existingTxt(end-1:end),'0$')
                    existingTxt=existingTxt(1:end-2);
                elseif strcmp(existingTxt(end),'$')
                    existingTxt=existingTxt(1:end-1);
                end
                
                if strcmp(termType,'Multiplier')
                    replaceTxt = strcat(existingTxt,strcat(['+(',coeff,')*'],stateterm,'$'));
                    textDyn{idx} = replaceTxt;
                else
                    replaceTxt = strcat(existingTxt,['+\int_',get(app.lowerLimit, 'Value'),...
                        '^',get(app.UpperLimit, 'Value'),'(',coeff,')*',stateterm,...
                        get(app.IntVariable,'Value'),'$']);
                    textDyn{idx} = replaceTxt;
                end
                
                set(app.TextArea,'Value',textDyn);
                textDyn=erase(textDyn,'*');
                cla(app.DynamicsEqns);
                text(app.DynamicsEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            elseif validTerm~=0
                tmp = errordlg("This term cannot be added to the selected equation");
            else
                if logicalval==2
                    tmp = errordlg("Input term has unidentified polynomial variable");
                elseif logicalval==3
                    tmp = errordlg("Input term has polynomial variable with non-integer exponent");
                else
                    tmp = errordlg("Input is not a valid polynomial");
                end
            end
        end

        % Cell edit callback: PDElist
        function changeDerivative(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            tabledata = get(app.PDElist,'Data');
            changedState = convertStringsToChars(tabledata(indices(1),1));
            list = get(app.ProductList,'Items');
            
            if str2double(event.NewData)>str2double(event.PreviousData)
                for i=str2double(event.PreviousData)+1:str2double(event.NewData)
                    list{end+1} = [changedState,'_{',repmat('s',1,i),'}(s)'];
                    list{end+1} = [changedState,'_{',repmat('s',1,i),'}(\theta)'];
                    j = i-1;
                    list{end+1} = [changedState,'_{',repmat('s',1,j),'}','(0)'];
                    list{end+1} = [changedState,'_{',repmat('s',1,j),'}','(1)'];
                end
            elseif str2double(event.NewData)<str2double(event.PreviousData)
                for i=str2double(event.NewData)+1:str2double(event.PreviousData)
                    list(strcmp(list,[changedState,'_{',repmat('s',1,i),'}(s)'])) = [];
                    list(strcmp(list,[changedState,'_{',repmat('s',1,i),'}(\theta)'])) = [];
                    j = i-1;
                    list(strcmp(list,[changedState,'_{',repmat('s',1,j),'}','(0)'])) = [];
                    list(strcmp(list,[changedState,'_{',repmat('s',1,j),'}','(1)'])) = [];
                end
            end
            set(app.ProductList,'Items',list);
        end

        % Button pushed function: BCNumberButton
        function addTerm2BC(app, event)
            a = get(app.lowerLimit, 'Value');
            b = get(app.UpperLimit, 'Value');
            stateterm = get(app.ProductList,'Value');
            termType = get(app.TermType,'Value');
            coeff = get(app.Coefficient,'Value');
            intvar= get(app.IntVariable,'Value');
            
            
            logicalval = isvalidPolynomial(coeff);
            
            
            coeff = replace(coeff,'theta','\theta');
            validTerm = isvalidTerm(coeff,termType,'BC',stateterm,intvar);
            
            if logicalval==0 && validTerm==0
                
                textDyn = get(app.TextArea_2,'Value');
                idx = str2double(get(app.BCNumber,'Value'));
                existingTxt = textDyn{idx+1};
                
                if strcmp(existingTxt(end-1:end),'0$')
                    existingTxt=existingTxt(1:end-2);
                elseif strcmp(existingTxt(end),'$')
                    existingTxt=existingTxt(1:end-1);
                end
                
                if strcmp(termType,'Multiplier')
                    replaceTxt = strcat(existingTxt,strcat(['+(',coeff,')*'],stateterm,'$'));
                    textDyn{idx+1} = replaceTxt;
                else
                    replaceTxt = strcat(existingTxt,['+\int_',get(app.lowerLimit, 'Value'),...
                        '^',get(app.UpperLimit, 'Value'),'(',coeff,')*',stateterm,...
                        get(app.IntVariable,'Value'),'$']);
                    textDyn{idx+1} = replaceTxt;
                end
                
                set(app.TextArea_2,'Value',textDyn);
                textDyn=erase(textDyn,'*');
                cla(app.BCEqns);
                text(app.BCEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            elseif validTerm~=0
                tmp = errordlg("This term cannot be added to the selected equation");
           else
                if logicalval==2
                    tmp = errordlg("Input term has unidentified polynomial variable");
                elseif logicalval==3
                    tmp = errordlg("Input term has polynomial variable with non-integer exponent");
                else
                    tmp = errordlg("Input is not a valid polynomial");
                end
            end
        end

        % Button pushed function: AddnewBCButton
        function addBC(app, event)
            textBC = get(app.TextArea_2,'Value');
            textBC{end+1} = ['$0 = 0$'];
            BClist = get(app.BCNumber,'Items');
            BClist{end+1} = num2str(length(BClist)+1);
            
            set(app.BCNumber,'Items',BClist);
            set(app.TextArea_2,'Value',textBC);
            cla(app.BCEqns);
            text(app.BCEqns,0,1,textBC,'VerticalAlignment','top','interpreter','latex');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PIETOOLS2021aUIFigure = uifigure('Visible', 'off');
            app.PIETOOLS2021aUIFigure.Name = 'PIETOOLS 2021a';
            app.PIETOOLS2021aUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PIETOOLS2021aUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AddnewBCButton = uibutton(app.LeftPanel, 'push');
            app.AddnewBCButton.ButtonPushedFcn = createCallbackFcn(app, @addBC, true);
            app.AddnewBCButton.Tag = 'AddDynamicsTerms';
            app.AddnewBCButton.Text = 'Add new BC';
            app.AddtoPanel = uipanel(app.LeftPanel);
            app.AddtoPanel.Title = 'Add to:';
            app.DynamicsofButton = uibutton(app.AddtoPanel, 'push');
            app.DynamicsofButton.ButtonPushedFcn = createCallbackFcn(app, @addTerms2Dynamics, true);
            app.DynamicsofButton.Tag = 'AddDynamicsTerms';
            app.DynamicsofButton.Text = 'Dynamics of';
            app.LHSDropDown = uidropdown(app.AddtoPanel);
            app.BCNumberButton = uibutton(app.AddtoPanel, 'push');
            app.BCNumberButton.ButtonPushedFcn = createCallbackFcn(app, @addTerm2BC, true);
            app.BCNumberButton.Tag = 'AddDynamicsTerms';
            app.BCNumberButton.Text = 'BC Number';
            app.BCNumber = uidropdown(app.AddtoPanel);
            app.BCNumber.Items = {};
            app.BCNumber.Value = {};
            app.ORLabel = uilabel(app.AddtoPanel);
            app.ORLabel.Text = 'OR';
            app.AddTermPanel = uipanel(app.LeftPanel);
            app.AddTermPanel.Title = 'Add Term';
            app.Coefficient = uieditfield(app.AddTermPanel, 'text');
            app.Coefficient.Tag = 'Coefficient';
            app.ProductList = uidropdown(app.AddTermPanel);
            app.ProductList.Tag = 'Int_Mul_state';
            app.TermType = uidropdown(app.AddTermPanel);
            app.TermType.Items = {'Multiplier', 'Integral', ''};
            app.TermType.Value = 'Multiplier';
            app.IntVariable = uidropdown(app.AddTermPanel);
            app.IntVariable.Items = {'ds', 'd\theta'};
            app.IntVariable.Tag = 'intVariable';
            app.IntVariable.Value = 'ds';
            app.aEditFieldLabel = uilabel(app.AddTermPanel);
            app.aEditFieldLabel.Text = 'a';
            app.lowerLimit = uieditfield(app.AddTermPanel, 'text');
            app.bEditFieldLabel = uilabel(app.AddTermPanel);
            app.bEditFieldLabel.Text = 'b';
            app.UpperLimit = uieditfield(app.AddTermPanel, 'text');
            app.CenterPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.CenterPanel);
            app.TextArea.Tag = 'Dynamics';
            app.TextArea.Visible = 'off';
            app.TextArea_2 = uitextarea(app.CenterPanel);
            app.TextArea_2.Tag = 'BCs';
            app.TextArea_2.Visible = 'off';
            app.DynamicsEqns = uiaxes(app.CenterPanel);
            title(app.DynamicsEqns, '')
            xlabel(app.DynamicsEqns, '')
            ylabel(app.DynamicsEqns, '')
            app.DynamicsEqns.XAxisLocation = 'origin';
            app.DynamicsEqns.XTick = [];
            app.DynamicsEqns.YAxisLocation = 'origin';
            app.DynamicsEqns.YTick = [];
            app.BCEqns = uiaxes(app.CenterPanel);
            title(app.BCEqns, '')
            xlabel(app.BCEqns, '')
            ylabel(app.BCEqns, '')
            app.BCEqns.XTick = [];
            app.BCEqns.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.ODElist = uitable(app.RightPanel);
            app.ODElist.ColumnName = {'ODE States'};
            app.ODElist.RowName = {};
            app.ODElist.Tag = 'ODElist';
            app.VariableNameEditFieldLabel = uilabel(app.RightPanel);
            app.VariableNameEditFieldLabel.Text = 'Variable Name';
            app.VariableNameEditField = uieditfield(app.RightPanel, 'text');
            app.AddPDEstateButton = uibutton(app.RightPanel, 'push');
            app.AddPDEstateButton.ButtonPushedFcn = createCallbackFcn(app, @addstatePDE, true);
            app.AddPDEstateButton.Tag = 'buttonAddPDE';
            app.AddPDEstateButton.Text = 'Add PDE state';
            app.AddODEstateButton = uibutton(app.RightPanel, 'push');
            app.AddODEstateButton.ButtonPushedFcn = createCallbackFcn(app, @addstateODE, true);
            app.AddODEstateButton.Tag = 'buttonAddODE';
            app.AddODEstateButton.Text = 'Add ODE state';
            app.PDElist = uitable(app.RightPanel);
            app.PDElist.ColumnName = {'PDE States'; 'Highest Derivative'};
            app.PDElist.RowName = {};
            app.PDElist.ColumnEditable = [false true];
            app.PDElist.CellEditCallback = createCallbackFcn(app, @changeDerivative, true);
            app.PDElist.Tag = 'PDElist';
            app.Inputlist = uitable(app.RightPanel);
            app.Inputlist.ColumnName = {'Inputs'};
            app.Inputlist.RowName = {};
            app.Inputlist.Tag = 'Inputlist';
            app.AddInputButton = uibutton(app.RightPanel, 'push');
            app.AddInputButton.ButtonPushedFcn = createCallbackFcn(app, @addInput, true);
            app.AddInputButton.Tag = 'buttonAddInput';
            app.AddInputButton.Text = 'Add Input';
            % Show the figure after all components are created
            app.PIETOOLS2021aUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIETOOLS_2021a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PIETOOLS2021aUIFigure)

            % Execute the startup function
            runStartupFcn(app, @InitializeGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PIETOOLS2021aUIFigure)
        end
    end
end